classdef dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        KontrolPaneli   matlab.ui.container.Panel
        TemizleButton   matlab.ui.control.Button
        AnalizEtButton  matlab.ui.control.Button
        GrntYkleButton  matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    
   properties (Access = private)
    net          % Eğitilmiş model
    currentImage % Yüklenen görüntü
   end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Model yükleme
    try
        modelPath = 'C:\Users\berka\OneDrive\Desktop\Brain_Dataset\brainTumorCNN.mat';
        load(modelPath, 'net');
        app.net = net;
        uialert(app.UIFigure, 'Model başarıyla yüklendi!', 'Başarılı', 'Icon', 'success');
    catch ME
        uialert(app.UIFigure, 'Model yüklenemedi! Lütfen dosya yolunu kontrol edin.', 'Hata', 'Icon', 'error');
    end

    % UIAxes baslangic ayari
    title(app.UIAxes, 'MRI Goruntusu Bekleniyor...');
    axis(app.UIAxes, 'off');
        end

        % Button pushed function: GrntYkleButton
        function GrntYkleButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp', 'Goruntu Dosyalari (*.jpg, *.png, *.jpeg, *.bmp)'}, ...
                             'MRI Goruntusu Secin');
    
    if file ~= 0
        % Goruntuyu oku
        app.currentImage = imread(fullfile(path, file));
        
        % Axes'de goster
        imshow(app.currentImage, 'Parent', app.UIAxes);
        title(app.UIAxes, 'Yuklenen MRI Goruntusu');
        
        % Bildirim goster
        uialert(app.UIFigure, 'Goruntu basariyla yuklendi! Simdi analiz edebilirsiniz.', ...
                'Basarili', 'Icon', 'success');
    end
        end

        % Button pushed function: AnalizEtButton
        function AnalizEtButtonPushed(app, event)
             % Goruntu kontrolu
    if isempty(app.currentImage)
        uialert(app.UIFigure, 'Lutfen once bir goruntu yukleyin!', ...
                'Uyari', 'Icon', 'warning');
        return;
    end
    
    % Model kontrolu
    if isempty(app.net)
        uialert(app.UIFigure, 'Model yuklenemedi! Lutfen uygulamayi yeniden baslatin.', ...
                'Hata', 'Icon', 'error');
        return;
    end
    
    % Goruntuyu isle (224x224 gri tonlama)
    imgProcessed = imresize(im2gray(app.currentImage), [224 224]);
    
    % Tahmin yap
    [prediction, scores] = classify(app.net, imgProcessed);
    confidence = max(scores) * 100;
    
   % Sonuclari goster
if prediction == "tumor"
    resultText = sprintf('TUMOR TESPIT EDILDI!\n\nGuven Orani: %.2f%%', confidence);
    iconType = 'warning';
    titleText = 'Tumor Tespit Edildi';
else
    resultText = sprintf('NORMAL\n\nTumor tespit edilmedi.\nGuven Orani: %.2f%%', confidence);
    iconType = 'success';
    titleText = 'Normal Sonuc';
end

% Sonuc dialogu goster
uialert(app.UIFigure, resultText, titleText, 'Icon', iconType);

% Goruntu uzerine sonuc yaz 
title(app.UIAxes, sprintf('\n %s (Guven: %.1f%%)', char(prediction), confidence), ...
      'FontSize', 14, 'FontWeight', 'bold');
        end

        % Button pushed function: TemizleButton
        function TemizleButtonPushed(app, event)
             % Axes'i temizle
    cla(app.UIAxes);
    title(app.UIAxes, 'MRI Goruntusu');
    
    % Degiskenleri sifirla
    app.currentImage = [];
    
    % Bildirim goster
    uialert(app.UIFigure, 'Ekran temizlendi.', 'Bilgi', 'Icon', 'info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KontrolPaneli = uipanel(app.UIFigure);
            app.KontrolPaneli.Title = 'Kontrol Paneli';
            app.UIAxes = uiaxes(app.KontrolPaneli);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GrntYkleButton = uibutton(app.KontrolPaneli, 'push');
            app.GrntYkleButton.ButtonPushedFcn = createCallbackFcn(app, @GrntYkleButtonPushed, true);
            app.GrntYkleButton.Text = 'Görüntü Yükle';
            app.AnalizEtButton = uibutton(app.KontrolPaneli, 'push');
            app.AnalizEtButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizEtButtonPushed, true);
            app.AnalizEtButton.Text = ' Analiz Et';
            app.TemizleButton = uibutton(app.KontrolPaneli, 'push');
            app.TemizleButton.ButtonPushedFcn = createCallbackFcn(app, @TemizleButtonPushed, true);
            app.TemizleButton.Text = ' Temizle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end